classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        RunButton                   matlab.ui.control.Button
        SimulateButtonGroup         matlab.ui.container.ButtonGroup
        WithoutFECButton_2          matlab.ui.control.RadioButton
        WithFECButton               matlab.ui.control.RadioButton
        minimumSNREditFieldLabel    matlab.ui.control.Label
        minimumSNREditField         matlab.ui.control.NumericEditField
        maximumSNREditFieldLabel    matlab.ui.control.Label
        maximumSNREditField         matlab.ui.control.NumericEditField
        SNRRangeindBLabel           matlab.ui.control.Label
        SystemOperatingButtonGroup  matlab.ui.container.ButtonGroup
        BPSKButton                  matlab.ui.control.RadioButton
        PAMButton                   matlab.ui.control.RadioButton
        ForFECLabel                 matlab.ui.control.Label
        ForFECDropDown              matlab.ui.control.DropDown
        RepitionnEditFieldLabel     matlab.ui.control.Label
        RepitionnEditField          matlab.ui.control.NumericEditField
        HammingnEditFieldLabel      matlab.ui.control.Label
        HammingnEditField           matlab.ui.control.NumericEditField
        RightPanel                  matlab.ui.container.Panel
        UIAxes                      matlab.ui.control.UIAxes
        NumberofbitsEditFieldLabel  matlab.ui.control.Label
        NumberofbitsEditField       matlab.ui.control.NumericEditField
        ClearButton                 matlab.ui.control.Button
        ExitButton                  matlab.ui.control.Button
        FairComparisonCheckBox      matlab.ui.control.CheckBox
        multipleGraphCheckBox       matlab.ui.control.CheckBox
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    methods (Access = private)
        
%         function results = func(app)
%             
%         end
%         function[test]=test_hw(app)
%             app.maximumSNREditField.Value=15;
%         end
        
           %%Fair %%check
 
        function [snr_fair] = Fair(app,Type,Snr,k,n)

        
        snr_fair = Snr;

            if (Type==1) %Repition
                %EncodedSignal_out=EncodedSignal./(sqrt(n));
                snr_fair = snr_fair - 10*log(n);
            elseif (Type==2) % hamming
              %EncodedSignal_out=EncodedSignal ./(sqrt(n/k));
              snr_fair = snr_fair - 10*log(n/k) ;
            end
            
        
        end   

        
        %AWGN (Didn't use and used awgn) (i.e. both random generator)
        function [Signal_Dis]=AWGN_BPSK_OR_PAM(app,SNR_DB,EncodedSignal)
            % Signal_Dis= awgn(EncodedSignal,SNR_DB) ; % or can use awgn
            noise=(randn(1,length(EncodedSignal))); %% generating a random Bits (this is the Noise)
            N0=1/(10^(SNR_DB/10)); %%this is the N0
            sigma=sqrt(N0/2); %%this is the sigma of the Noise
            noise=noise*sigma; %%Then muliply the sigma by the Noise I have
            Signal_Dis=(EncodedSignal+noise); %%Then addedd the Noise to the Encoded Bits
            
        end
        
        %%Reciever of BPSK without AWGN
        function[DecodedSignal]= Receiver_without_FEC(app,Bits)
            
            for i=1:length(Bits)
                if(Bits(i)<0)
                    DecodedSignal(i)=-1;
                elseif(Bits(i)>=0)
                    DecodedSignal(i)=1;
                end
            end
            
            %Demodulation BPSK
            %             for i=1:length(DecodedSignal)
            %                 if(DecodedSignal(i)==-1)
            %                     DecodedSignal(i)=0;
            %                 end
            %             end
        end


        %%BPSK Modulation and Demodulation
        
        function [Modulated]=BPSK_Modulation(app,stream)
            
            Modulated = stream;
            for i=1:length(stream)
                if(stream(i)==0)
                    Modulated(i)=-1;
                end
            end
            
        end
        
        function [Demodulated]=BPSK_Demodulation(app,Bits)
            
            Demodulated = Bits;
            for i=1:length(Bits)
                
                if(Bits(i)<0)
                    
                    Demodulated(i)=0;
                    
                elseif(Bits(i)>=0)
                    
                    Demodulated(i)=1;
                    
                end
                
            end
        end


        %PAM Modulation
        %%PAM Modulation and Demodulation
        function [mess3]=fn_Pam_demodulation(app,Noisy)
            Length_of_received_signal=length(Noisy);
            mess3=[];
            for j=1:1:Length_of_received_signal
                if(Noisy(j)==-7 ||Noisy(j)<-7 || (Noisy(j) > -7 && Noisy(j) < -6) )
                    mess3(j)=0;
                elseif(Noisy(j)==-5 || (Noisy(j) > -6 && Noisy(j) < -4))
                    mess3(j)=1;
                elseif(Noisy(j)==-1 || ((Noisy(j) > -2 && Noisy(j) < 0)))
                    mess3(j)=2;
                elseif(Noisy(j)==-3 || (Noisy(j) > -4 && Noisy(j) < -2))
                    mess3(j)=3;
                elseif(Noisy(j)==7 ||Noisy(j)>6)
                    mess3(j)=4;
                elseif(Noisy(j)==5 || (Noisy(j) > 4 && Noisy(j) < 6))
                    mess3(j)=5;
                elseif(Noisy(j)==1 || (Noisy(j) > 0 && Noisy(j) < 2))
                    mess3(j)=6;
                elseif(Noisy(j)==3 || (Noisy(j) > 2 && Noisy(j) < 4))
                    mess3(j)=7;
                end
                
            end
        end
        
        function [mess1]=fn_PAM_Modulation(app,message)
            p=length(message);
            mess1=[];
            for j=1:1:p
                if(message(j)==0)
                    mess1(j)=-7;
                elseif(message(j)==1)
                    mess1(j)=-5;
                elseif(message(j)==2)
                    mess1(j)=-1;
                elseif(message(j)==3)
                    mess1(j)=-3;
                elseif(message(j)==4)
                    mess1(j)=7;
                elseif(message(j)==5)
                    mess1(j)=5;
                elseif(message(j)==6)
                    mess1(j)=1;
                elseif(message(j)==7)
                    mess1(j)=3;
                end
                
            end
        end
        
        
        %%functions used in PAM
        function [PAM_Decoder1]=fn_decimal_binary(app,PAM_Decoder)
            %Pam from decimal to binary%%%%%%%%%%%%%%%%%%%
            Length_Pam_decoder=length(PAM_Decoder);
            PAM_Decoder1=[];
            p=1;
            for j=1:1:Length_Pam_decoder
                if(PAM_Decoder(j)==0)
                    PAM_Decoder1(p)=0;
                    PAM_Decoder1(p+1)=0;
                    PAM_Decoder1(p+2)=0;
                elseif(PAM_Decoder(j)==1)
                    PAM_Decoder1(p)=0;
                    
                    PAM_Decoder1(p+1)=0;
                    
                    PAM_Decoder1(p+2)=1;
                    
                elseif(PAM_Decoder(j)==2)
                    PAM_Decoder1(p)=0;
                    PAM_Decoder1(p+1)=1;
                    PAM_Decoder1(p+2)=0;
                    
                elseif(PAM_Decoder(j)==3)
                    PAM_Decoder1(p)=0;
                    PAM_Decoder1(p+1)=1;
                    PAM_Decoder1(p+2)=1;
                elseif(PAM_Decoder(j)==4)
                    PAM_Decoder1(p)=1;
                    PAM_Decoder1(p+1)=0;
                    PAM_Decoder1(p+2)=0;
                elseif(PAM_Decoder(j)==5)
                    PAM_Decoder1(p)=1;
                    PAM_Decoder1(p+1)=0;
                    PAM_Decoder1(p+2)=1;
                elseif(PAM_Decoder(j)==6)
                    PAM_Decoder1(p)=1;
                    PAM_Decoder1(p+1)=1;
                    PAM_Decoder1(p+2)=0;
                elseif(PAM_Decoder(j)==7)
                    PAM_Decoder1(p)=1;
                    PAM_Decoder1(p+1)=1;
                    PAM_Decoder1(p+2)=1;
                end
                p=p+3;
            end
        end
        
        function [message]=fn_conversion_Bin_decimal(app,Message)
            m=length(Message);
            message=[];
            a=mod(m,3);
            if(a==0)
                p=1;
                for j=1:1:m/3
                    message(j)=(1*Message(p+2))+(2*Message(p+1))+(4*Message(p));
                    p=p+3;
                end
            else
                disp("There are single or double bit in array are remaining");
            end
        end


        %%Repition
        
        function [Encoded_Repetition] = Repetition_Encoder (app,n,message)
            
            Encoded_Repetition = zeros(1,n*length(message)); % empty array
            
            k=1;
            
            for i=1:n: (length(Encoded_Repetition)-1)
                
                for j=1:1:n
                    Encoded_Repetition(1,i+j-1) = message(k);
                end
                k = k+1 ;
                
            end
            
            %Encoded_BPSK_Repetition = real( pskmod(Encoded_Repetition,2,pi) );
            
        end
        
        function [Decoded_BPSK] = Repetition_Decoder (app,n,Recieved)
            
            
            Decoded_BPSK = zeros(1,length(Recieved)/n);
            
            
            for i=1:(length(Recieved)/n)
                
                num_ones = 0;
                num_zeros = 0;
                for j=1:1:n
                    
                    if Recieved(n*(i-1)+j)== 1
                        num_ones = num_ones +1;
                    else
                        num_zeros = num_zeros +1;
                    end
                end
                if(num_ones>num_zeros)
                    Decoded_BPSK(1,i)=1;
                elseif (num_ones<num_zeros)
                    Decoded_BPSK(1,i)=0;
                else
                    Decoded_BPSK(1,i)=1;   %n is even and equal number of errors for both which is n/2 so majority rule can't be achieved
                end
                
            end
            
            
        end
        
        
        %%Hamming
        
        function [EncodedMessage,PaddingZero] = Hamming_Encoder (app,n,message)
            
            %Encoded_BPSK_Hamming 1*n
            %message 1*k
            %n = {7, 15, 31}
            %k = {4, 11, 26}
            %m = {3, 4 , 5 }
            %Generating matrix is k*n
            if n==7
                m=3;
                GenMat= gen2par(hammgen(m));
                k=n-m;
            elseif n==15
                m=4;
                GenMat= gen2par(hammgen(m));
                k=n-m;
            elseif n==31
                m=5;
                GenMat= gen2par(hammgen(m));
                k=n-m;
            end
            
            lengthOfMessage=length(message);
            %%no of padding zeros
            PaddingZero = mod((k-mod(lengthOfMessage,k)),k);
            padded_array = [zeros(1,PaddingZero),message];
            length_new_message = PaddingZero+lengthOfMessage;   %length of new encoded message without hamming
            EncodedMessage=zeros([1 round(length_new_message*(n/k))]); %number of i/ps included
            
            encoded_diff_size= rem(transpose(fliplr(reshape(padded_array,k,[])))*GenMat,2);
            EncodedMessage=reshape(encoded_diff_size.',1,[]);
            
            % %Another method
            % for i = 1:(length_new_message/k)
            %   EncodedMessage(n*(i-1)+1:n*(i-1)+n)=rem(padded_array(k*(i-1)+1:k*(i-1)+k)*GenMat,2);
            % end
            
            
        end
        
        function [DecodedMessage] = Hamming_Decoder (app,n,Recieved,padding)
            
            %Encoded_BPSK_Hamming 1*n
            %message 1*k
            %n = {7, 15, 31}
            %k = {4, 11, 26}
            %m = {3, 4 , 5 }
            %Generating matrix is k*n
            
            % dmin= 3 -> correct 1 bit
            % all syndrome can correct 1 bit and detect 2 bit, but cannot correct them
            %so more than 1 bit error will be a repeated syndrome so that we take the
            %the less number of errors which is one bit
            %number of states of syndrome equals 2^n-k = 2^m
            
            if n == 7
                GenMat= gen2par(hammgen(3)); %%%%%%%hammgen(m)
                %H_trans = [eye(3);GenMat(:,1:3)];
                H_trans = transpose(hammgen(3));
                %Error=[zeros(1,7);eye(7)];
                %syndrome_table = Error*H_trans;
                syndrome_table=syndtable(transpose(H_trans));
                k=4;
                m=3;
            elseif n == 15
                GenMat = gen2par(hammgen(4));%%%%%%%hammgen(m)
                %H_trans = [eye(3);GenMat(:,1:4)];
                H_trans = transpose(hammgen(4));
                %Error=[zeros(1,15);eye(15)];
                %syndrome_table = Error*H_trans;
                syndrome_table=syndtable(transpose(H_trans));
                k=11;
                m=4;
            elseif n == 31
                GenMat = gen2par(hammgen(5));%%%%%%%hammgen(m)
                %H_trans = [eye(3);GenMat(:,1:5)];
                H_trans = transpose(hammgen(5));
                %Error=[zeros(1,31);eye(31)];
                %syndrome_table = Error*H_trans;
                syndrome_table=syndtable(transpose(H_trans));
                k=26;
                m=5;
            end
            
            
            % syndrome = mod(Recieved*H_trans,2);
            % for i=1:1: (n+1)
            % if syndrome_table(i,(1:m))== syndrome
            %     %disp("test1")
            %     error_found =  Error(i,(1:n));
            % end
            % end
            % corrected = mod(Recieved + error_found, 2);%+ mod(syndrome*((H_trans)^(-1)),2) ;
            % Decoded_BPSK_Hamming = corrected(k:n) ;
            
            %The above code for correcting at the same time if you don't want to
            %calculate BER
            
            
        %   DecodedMessage=zeros([1,length(Recieved)*(k/n)]);

            if n ==15
   
                k1 = 5;

            elseif n==7
    
                k1=4;
            elseif n==31
   
                k1=6;
            end
            for i= 1:(length(Recieved)/n)
                  VHT = bi2de(rem(flip(transpose(fliplr(reshape(Recieved,n,[]))))*H_trans,2),'left-msb'); % Convert to decimal.
                  e = syndrome_table(1+VHT,:); 
                  CorrectedMessage=rem(e+flip(transpose(fliplr(reshape(Recieved,n,[])))),2);    
   
            end
            CorrectedMessage=CorrectedMessage(:,[m+1:end]);
            CorrectedMessage=flip(CorrectedMessage);
            DecodedMessage=reshape(CorrectedMessage.',1,[]);
            DecodedMessage = DecodedMessage(1,padding+1:end);
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ForFECDropDown.Enable = 'off';
            app.HammingnEditField.Enable = 'off';
            app.RepitionnEditField.Enable = 'off';
            
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {219, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            cla(app.UIAxes, 'reset');
        end

        % Selection changed function: SimulateButtonGroup
        function SimulateButtonGroupSelectionChanged(app, event)
            selectedButton = app.SimulateButtonGroup.SelectedObject.Text;
            
            if selectedButton == "With FEC"
                % disp("test1");
                app.ForFECDropDown.Enable = 'on';
                
                if app.ForFECDropDown.Value == "Repition code"
                    app.RepitionnEditField.Enable = 'on';
                else
                    app.HammingnEditField.Enable = 'on';
                end
                
            else
                app.ForFECDropDown.Enable = 'off';
                app.HammingnEditField.Enable = 'off';
                app.RepitionnEditField.Enable = 'off';
                
            end
            
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
           app.delete ;
        end

        % Value changed function: ForFECDropDown
        function ForFECDropDownValueChanged(app, event)
            value = app.ForFECDropDown.Value;
            
            if value == "Repition code"
                %  disp("test1");
                app.RepitionnEditField.Enable = 'on';
            else
                app.RepitionnEditField.Enable = 'off';
                % disp("test2");
            end
            
            if value == "Hamming Code"
                % disp("test1");
                app.HammingnEditField.Enable = 'on';
            else
                app.HammingnEditField.Enable = 'off';
                %disp("test2");
            end
            
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
        
            if app.multipleGraphCheckBox.Value == 1
                %disp("test");
                hold(app.UIAxes,'on');
                %legend(app.UIAxes,'Location','southoutside');
                
            end
        
            %% if stream of bits are entered
            %%bit_stream = str2double(strsplit(app.InputStreamofBitseg1010EditField.Value,' '));
            SNR_min = app.minimumSNREditField.Value;
            SNR_max = app.maximumSNREditField.Value;
            if SNR_max <=SNR_min
                uialert(uifigure,'SNR_max should be > SNR_min','Invalid entery');
                return;
            end
            Number_of_bits = app.NumberofbitsEditField.Value;
            
            if Number_of_bits <=0
                uialert(uifigure,'Number of bits should be > 0','Invalid entery');
                return;
            end
            bit_stream=round(rand(1,Number_of_bits));
            Fair_comparison = app.FairComparisonCheckBox.Value;
            BPSK_PAM = app.SystemOperatingButtonGroup.SelectedObject.Text;
            
            Error_Bit=0;
            BER=[];
            
            %Fair
            if app.FairComparisonCheckBox.Value == 1
                if app.SimulateButtonGroup.SelectedObject.Text == "With FEC"
                
                    if app.ForFECDropDown.Value == "Repition code"
                     n = app.RepitionnEditField.Value;
                    SNR_max = Fair(app,1,SNR_max,1,n);             
                    SNR_min = Fair(app,1,SNR_min,1,n);
                    else
                        n = app.HammingnEditField.Value;
                        if n == 7
                            k = 4;
                        elseif n == 15
                            k = 11;
                        elseif n ==31
                            k = 26;
                        end
                        SNR_max = Fair(app,2,SNR_max,k,n);             
                        SNR_min = Fair(app,2,SNR_min,k,n);
                    end
                end
            end
            
            %
                  
            
            if app.SimulateButtonGroup.SelectedObject.Text == "With FEC"
                
                if app.ForFECDropDown.Value == "Repition code"  %repition
                    
                    Encoded_bits = Repetition_Encoder(app,app.RepitionnEditField.Value,bit_stream);
                    
                    if BPSK_PAM == "BPSK"
                        
                        Stream_modulated = BPSK_Modulation(app,Encoded_bits);
                        
                    else
                        Stream_modulated = fn_PAM_Modulation(app,Encoded_bits);
                        % test_hw(app);
                        %    uialert(uifigure,'File not found','Invalid File');
                    end
                    
                    counter=1;
                    Error_Bit=0;
                    BER=[];
                    for j=SNR_min:((SNR_max-SNR_min)/10):SNR_max
                        
                        BER(counter)=0;
                        Signal_Dis = awgn(Stream_modulated,j);
                        %counter = counter + 1;
                        % test=Receiver_without_FEC(app,Signal_Dis);
                        if BPSK_PAM == "BPSK"
                            
                            Received_Signal = BPSK_Demodulation(app,Signal_Dis);
                            
                            
                        else
                            
                            Received_Signal = fn_Pam_demodulation(app,Signal_Dis);
                            
                            
                        end
                        
                        Received_Signal=Repetition_Decoder(app,app.RepitionnEditField.Value,Received_Signal);


                        for i=1:1:length(Received_Signal)
                            
                            if Received_Signal(i)~=bit_stream(i)
                                Error_Bit=Error_Bit+1;
                                
                            end
                            
                        end
                        Error_Bit =Error_Bit/length(bit_stream);
                        BER(counter) = Error_Bit;
                        counter=counter+1;
                        
                    end
                    
                    SNR_min = app.minimumSNREditField.Value;
                    SNR_max = app.maximumSNREditField.Value;
                    
                    P=0.5*erfc(sqrt(10.^(SNR_min:((SNR_max-SNR_min)/10):SNR_max)));
                    
                    semilogy(app.UIAxes,(SNR_min:((SNR_max-SNR_min)/10):SNR_max),BER,'LineWidth',2);       %Plot the line code
                    title(app.UIAxes,'BER vs SNR');                
                    %   legend(app.UIAxes,{'Repition'},'Location','southoutside');
                
                else    %hamming
                    
                    if app.HammingnEditField.Value ~= 7 && app.HammingnEditField.Value ~= 15 && app.HammingnEditField.Value ~= 31
                        uialert(uifigure,'n should be 7 or 15 or 31','Invalid entery');
                        return;
                    end
                    
                   
                    [Encoded_bits,padding] = Hamming_Encoder(app,app.HammingnEditField.Value,bit_stream);
                    
                    if BPSK_PAM == "BPSK"
                        
                        Stream_modulated = BPSK_Modulation(app,Encoded_bits);
                        
                    else
                        Stream_modulated = fn_PAM_Modulation(app,Encoded_bits);
                      
                    end
                    
                  counter=1;
                  Error_Bit=0;
                  BER=[];
                    
                    
                    for j=SNR_min:((SNR_max-SNR_min)/10):SNR_max
                        
                        BER(counter)=0;
                        Signal_Dis = awgn(Stream_modulated,j);
                        %counter = counter + 1;
                        % test=Receiver_without_FEC(app,Signal_Dis);
                        if BPSK_PAM == "BPSK"
                            
                            Received_Signal = BPSK_Demodulation(app,Signal_Dis);
                            
                            
                        else
                            
                            Received_Signal = fn_Pam_demodulation(app,Signal_Dis);
                            
                            
                        end
                        
                        Received_Signal=Hamming_Decoder(app,app.HammingnEditField.Value,Received_Signal,padding);


                        for i=1:1:length(Received_Signal)
                            
                            if Received_Signal(i)~=bit_stream(i)
                                Error_Bit=Error_Bit+1;
                                
                            end
                            
                        end
                        Error_Bit =Error_Bit/length(Received_Signal);
                        BER(counter) = Error_Bit;
                        counter=counter+1;
                        
                    end
                    
                    SNR_min = app.minimumSNREditField.Value;
                    SNR_max = app.maximumSNREditField.Value;
                    
                    semilogy(app.UIAxes,(SNR_min:((SNR_max-SNR_min)/10):SNR_max),BER,'LineWidth',2);       %Plot the line code
                 %   legend(app.UIAxes,{'Hamming'},'Location','southoutside');
                    title(app.UIAxes,'BER vs SNR');
                    
                end
                
            else    %without FEC
                
                if BPSK_PAM == "BPSK"
                    
                    Stream_modulated = BPSK_Modulation(app,bit_stream);    
                    
                else
                    Stream_modulated = fn_PAM_Modulation(app,bit_stream);
                    
                end
                counter=1;
                Error_Bit=0;
                BER=[];
                for j=SNR_min:((SNR_max-SNR_min)/10):SNR_max
                    
                    BER(counter)=0;
                    Signal_Dis = awgn(Stream_modulated,j);
                    %counter = counter + 1;
                    % test=Receiver_without_FEC(app,Signal_Dis);
                    if BPSK_PAM == "BPSK"
                        
                        Received_Signal = BPSK_Demodulation(app,Signal_Dis);
                        
                    else
                        Received_Signal=fn_Pam_demodulation(app,Signal_Dis);
                    end


                    for i=1:1:length(Received_Signal)
                        
                        if Received_Signal(i)~=bit_stream(i)
                            Error_Bit=Error_Bit+1;
                            
                        end
                        
                    end
                    Error_Bit =Error_Bit/length(Received_Signal);
                    BER(counter) = Error_Bit;
                    counter=counter+1;
                    
                end
                
                
                P=0.5*erfc(sqrt(10.^((SNR_min:1:SNR_max)/10)));
                
                semilogy(app.UIAxes,(SNR_min:((SNR_max-SNR_min)/10):SNR_max),BER,'LineWidth',2);
                title(app.UIAxes,'BER vs SNR');
              %  legend(app.UIAxes,{'without FEC'},'Location','southoutside');
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RunButton = uibutton(app.LeftPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.SimulateButtonGroup = uibuttongroup(app.LeftPanel);
            app.SimulateButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SimulateButtonGroupSelectionChanged, true);
            app.SimulateButtonGroup.Title = 'Simulate';
            app.WithoutFECButton_2 = uiradiobutton(app.SimulateButtonGroup);
            app.WithoutFECButton_2.Text = 'Without FEC';
            app.WithoutFECButton_2.Value = true;
            app.WithFECButton = uiradiobutton(app.SimulateButtonGroup);
            app.WithFECButton.Text = 'With FEC';
            app.minimumSNREditFieldLabel = uilabel(app.LeftPanel);
            app.minimumSNREditFieldLabel.Text = 'minimum SNR';
            app.minimumSNREditField = uieditfield(app.LeftPanel, 'numeric');
            app.maximumSNREditFieldLabel = uilabel(app.LeftPanel);
            app.maximumSNREditFieldLabel.Text = 'maximum SNR';
            app.maximumSNREditField = uieditfield(app.LeftPanel, 'numeric');
            app.SNRRangeindBLabel = uilabel(app.LeftPanel);
            app.SNRRangeindBLabel.Text = 'SNR Range in dB';
            app.SystemOperatingButtonGroup = uibuttongroup(app.LeftPanel);
            app.SystemOperatingButtonGroup.Title = 'System Operating';
            app.BPSKButton = uiradiobutton(app.SystemOperatingButtonGroup);
            app.BPSKButton.Text = 'BPSK';
            app.BPSKButton.Value = true;
            app.PAMButton = uiradiobutton(app.SystemOperatingButtonGroup);
            app.PAMButton.Text = '8-PAM';
            app.ForFECLabel = uilabel(app.LeftPanel);
            app.ForFECLabel.Text = 'For FEC  ';
            app.ForFECDropDown = uidropdown(app.LeftPanel);
            app.ForFECDropDown.Items = {'Repition code', 'Hamming Code'};
            app.ForFECDropDown.ValueChangedFcn = createCallbackFcn(app, @ForFECDropDownValueChanged, true);
            app.ForFECDropDown.Value = 'Repition code';
            app.RepitionnEditFieldLabel = uilabel(app.LeftPanel);
            app.RepitionnEditFieldLabel.Text = 'Repition n: ';
            app.RepitionnEditField = uieditfield(app.LeftPanel, 'numeric');
            app.HammingnEditFieldLabel = uilabel(app.LeftPanel);
            app.HammingnEditFieldLabel.Text = 'Hamming n:';
            app.HammingnEditField = uieditfield(app.LeftPanel, 'numeric');
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'BER')
            xlabel(app.UIAxes, 'SNR (dB)')
            ylabel(app.UIAxes, 'BER (dB)')
            app.NumberofbitsEditFieldLabel = uilabel(app.RightPanel);
            app.NumberofbitsEditFieldLabel.Text = 'Number of bits';
            app.NumberofbitsEditField = uieditfield(app.RightPanel, 'numeric');
            app.ClearButton = uibutton(app.RightPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.ExitButton = uibutton(app.RightPanel, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.FairComparisonCheckBox = uicheckbox(app.RightPanel);
            app.FairComparisonCheckBox.Text = 'Fair Comparison';
            app.multipleGraphCheckBox = uicheckbox(app.RightPanel);
            app.multipleGraphCheckBox.Text = 'multiple Graph';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end